classdef SEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SegmentWBCButton                matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        LoadImageButton                 matlab.ui.control.Button
        WBCCounterandSegmentationLabel  matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        SegmentationLabel               matlab.ui.control.Label
        CounterLabel                    matlab.ui.control.Label
        CountButton                     matlab.ui.control.Button
        WBCEditField                    matlab.ui.control.NumericEditField
        WBCEditFieldLabel               matlab.ui.control.Label
        RBCEditField                    matlab.ui.control.NumericEditField
        RBCEditFieldLabel               matlab.ui.control.Label
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        a % image
        WBC %WBC
        RBC %RBC
        J1 %RBCimage
        t %WBCimage
        amask %segfinal
        a1 %grayscale
        o %RBCup
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
         [file,path] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           file=strcat(path,file);
           app.a=imread(file);
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            imshow(app.a,'Parent',app.UIAxes3);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
        app.RBCEditField.Value=0;
        app.WBCEditField.Value=0;
        clear;
        clc;
        end

        % Button pushed function: CountButton
        function CountButtonPushed(app, event)
            b=rgb2gray(app.a);

J = adapthisteq(b,'clipLimit',0.02,'Distribution','rayleigh');

level=graythresh(J);
bw=imbinarize(J,level);
k=~bw;
bw4 = imfill(k,'holes');
bw5 = medfilt2(bw4);
bw6=bwareaopen(bw5,500);
bw7 = imfill(bw6,'holes');        
se = strel('cube',3);
I = imerode(bw7,se);
label=bwlabel(I);
stats=regionprops(label,'Solidity','Area');
density=[stats.Solidity];
area=[stats.Area];
high_dense_area=density>0.5;
max_area=max(area(high_dense_area));
t_label=find(area==max_area);
app.t=ismember(label,t_label);
se=strel('square',5);
app.t=imdilate(app.t,se);
h = bwperim(app.t);
CC = bwconncomp(h);
app.WBC=CC.NumObjects;
R1=(I-app.t);
app.J1=imfill(R1);
h1 = bwperim(app.J1);
CC2 = bwconncomp(h1);
app.RBC=CC2.NumObjects;
app.RBCEditField.Value=app.RBC;
app.WBCEditField.Value=app.WBC;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            imshow(app.J1,'Parent',app.UIAxes);
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            imshow(app.t,'Parent',app.UIAxes2);
        end

        % Value changed function: RBCEditField
        function RBCEditFieldValueChanged(app, event)
      
        end

        % Button pushed function: SegmentWBCButton
        function SegmentWBCButtonPushed(app, event)
app.a1=rgb2gray(app.a);
g = app.a(:, :, 2);
a2= zeros(size(app.a1, 1), size(app.a1, 2));
just_green = cat(3, a2, g, a2);
g = just_green(:, :, 2);
B = imlocalbrighten(g );
L=imadjust(B);
H = histeq(B);
R1=imadd(L,H);
R2 = imsubtract(R1,H);
R3=imadd(R1,R2);
R4=imadd(R3,H);
for i=1:3
    R5=ordfilt2(R4,1,ones(3,3));
end
level=graythresh(R5);
bw=imbinarize(R5,level);
se = strel('cube',3);
I = imerode(bw,se);

filterStart=tic;
d= strel('disk',9);
bw=imopen(I,d);

out_mask = bw;
    cells = bwconncomp(bw);
    no_of_WBCs=cells.NumObjects;    
    a2 = app.a1;
    for j=1:no_of_WBCs
        %return the coordinates for the pixels in object j
        [r, c] = find(bwlabel(bw)==j);
        rc = [r c];
        %marking the location of the nuclei of white blood cells by a white
        %colour
        for i=1:max(size(rc))
            a2(rc(i,1),rc(i,2),:)=uint8(app.a1(rc(i,1),rc(i,2),:)*1.5);
        end
    end

    bw1 = imcomplement(bw);
    app.amask = app.a;
    app.amask(bw1) = 255;
    
        end

        % Button down function: UIAxes5
        function UIAxes5ButtonDown(app, event)
            imshow(app.a1,'Parent',app.UIAxes5);
        end

        % Button down function: UIAxes4
        function UIAxes4ButtonDown(app, event)
            imshow(app.amask,'Parent',app.UIAxes4);
        end

        % Value changed function: WBCEditField
        function WBCEditFieldValueChanged(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'RBC')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'WBC')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Original Image')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Segmented WBC')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Gray Image')
            app.UIAxes5.XTick = [];
            app.UIAxes5.XTickLabel = '';
            app.UIAxes5.YTick = [];
            app.UIAxes5.ButtonDownFcn = createCallbackFcn(app, @UIAxes5ButtonDown, true);
            app.RBCEditFieldLabel = uilabel(app.UIFigure);
            app.RBCEditFieldLabel.Text = 'RBC';
            app.RBCEditField = uieditfield(app.UIFigure, 'numeric');
            app.RBCEditField.ValueChangedFcn = createCallbackFcn(app, @RBCEditFieldValueChanged, true);
            app.WBCEditFieldLabel = uilabel(app.UIFigure);
            app.WBCEditFieldLabel.Text = 'WBC';
            app.WBCEditField = uieditfield(app.UIFigure, 'numeric');
            app.WBCEditField.ValueChangedFcn = createCallbackFcn(app, @WBCEditFieldValueChanged, true);
            app.CountButton = uibutton(app.UIFigure, 'push');
            app.CountButton.ButtonPushedFcn = createCallbackFcn(app, @CountButtonPushed, true);
            app.CountButton.Text = 'Count';
            app.CounterLabel = uilabel(app.UIFigure);
            app.CounterLabel.Text = 'Counter';
            app.SegmentationLabel = uilabel(app.UIFigure);
            app.SegmentationLabel.Text = 'Segmentation';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '2413558.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = '2413558.jpg';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = '2413558.jpg';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = '2413558.jpg';
            app.WBCCounterandSegmentationLabel = uilabel(app.UIFigure);
            app.WBCCounterandSegmentationLabel.Text = {'WBC Counter '; 'and'; 'Segmentation'};
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SegmentWBCButton = uibutton(app.UIFigure, 'push');
            app.SegmentWBCButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentWBCButtonPushed, true);
            app.SegmentWBCButton.Text = 'Segment WBC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end